classdef AdaptiveShuffle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NextButton            matlab.ui.control.Button
        SelectFolderButton    matlab.ui.control.Button
        PlayPauseButton       matlab.ui.control.StateButton
        AdaptiveShuffleLabel  matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        LikeButton            matlab.ui.control.ToggleButton
        DislikeButton         matlab.ui.control.ToggleButton
        Button                matlab.ui.control.Button
        GenreLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        currentSong % Holds the index for the current song
        playlist % Playlist of user's randomly organized songs
        player % Music player for the playlist
        playStart % Decides if the first song has started
        featureMatrix % Extracted features of each file
        sampleFreq % Sampling frequency
        numFeats % Number of features to be extracted
        numAudio % Number of audio files in playlist
        blockSize % Block size for features
        hopSize % Hop size for features
        likeSong % Tag the song as liked
        disSong % Tag song as disliked
        likeSpace % Feature space for liked songs
        disSpace % Feature space for disliked songs
        kValue % Value for number of neighbors in kNN algorithm
        changeK % Alternates between 0 and 1 to change the k value
        overPlaylist % Playlist storage for overflow
        overFeatures % Feature storage for overflow
        overSize % Size of overflow
    end

    methods (Access = private)
    
        
        function genre = checkGenre(app)
            % Initialize
            music = app.playlist;
            song = app.currentSong;
            
            % Get the last sample of the audio file (genre tag) 
            lastSample = music{song}(length(music{song}));
            
            % Check what genre the last sample represents
            if (lastSample == .01)
                genre = 'Target';
            elseif (lastSample == .02)
                genre = 'Not Target';
            else 
                genre = 'Error';
            end
        end
        
        function [features] = extractFeatures(app, audioFile, block, hop, fs)

            %% Feature Extraction
            % Blocks an audio signal and calls the individual feature extraction functions.
            % (Spectral Features)
            %
            % inputs:
            %   audioFile:   (samples x N) input signal
            %   blockSize:   int, size of each block
            %   hopSize:     int, hop size
            %   fs:          int, sampling frequency of x
            % output:
            %   features: (7 x numBlocks) extracted features for each block of audio 

            % block the audio signal
            [xb, ~] = blockAudio(app, audioFile, block, hop, fs);

            % get dimensions of blocked audio
            [block, numBlocks] = size(xb);

            % initialize feature matrix
            features = ones(7, numBlocks);

            % call each feature to extract from audio file
            features(1,:) = spectralCentroid(app, xb, block, fs);
            features(2,:) = spectralRolloff(app, xb, block, .85);
            features(3,:) = spectralFlux(app, xb, block);
            features(4,:) = zeroCrossing(app, xb);

            % get mfcc features
            mfcc = melFreq(app, xb, block, 3, fs);
            n = 1;
            for i = 5:7 
                features(i,:) = mfcc(n,:);
                n = n+1;
            end
        end
        
        function [xb, timeInSec] = blockAudio(app, x, block, hop, fs)

            %% Blocks the input audio signal into overlapping buffers
            %
            % input:
            %   x:          (N * 1) float vector, input audio signal
            %   blockSize:  integer, size of each block
            %   hopSize:    integer, hop size
            %   fs:         float, sampling rate in Hz
            % output:
            %   xb:         (blockSize x numBlocks) float matrix, where numBlocks is 
            %               the number of blocks
            %   timeInSec:  (numBlocks x 1) float vector, time stamp (sec) of each block   

            % Check input dimensions 
            [~,n] = size(x);
            if (n>1)
            error('illegal input signal');
            end
            [m,n] = size(block);
            if (m ~= 1 && n ~=1)
                error('illegal blockSize');
            end
            [m,n] = size(hop);
            if (m ~= 1 && n ~=1)
                error('illegal hopSize');
            end
            [m,n] = size(fs);
            if (m ~= 1 && n ~=1)
                error('illegal fs');
            end

            % get signal length
            signal_length = length(x);
    
            % compute number of blocks in audio
            num_blocks = ceil(signal_length/hop);
    
            % zero pad incoming signal appropriately
            zero_pad = num_blocks*hop + block - signal_length;
            x = [x; zeros(zero_pad, 1)];
    
            % split signal into blocks
            xb = zeros(block, num_blocks); % initialize output matrix
            for i = 1:num_blocks
                xb(:, i) = x((i-1)*hop+1:(i-1)*hop + block);
            end

            % create time stamps 
            timeInSec = 1:hop:(num_blocks - 1)*hop + 1;
            timeInSec = timeInSec - 1;
            timeInSec = timeInSec' / fs;

        end
        
        function y = hannWindow(app, block)

            %% Hann Windowing Function
            % Creates a Hann window matrix.

            % input:
            %   blockSize:  integer, size of one block
            %
            % ouput:
            %   y:      (blockSize x 1) Hann window matrix of size blockSize

            % create matrix to store indices
            y = (0:block-1)';

            % compute the values using sin^2 formula 
            y = power(sin(pi*y/(block-1)),2);

        end
        
        function [aggrFeatures] = aggregateFeatures(app, features, audioFile)

            %% Feature Aggregation
            % Aggregates the values from the extracted features and returns the 
            % mean and variance for the whole audio file. Also extract rhythmic features.
            %
            % input:
            %   features: (7 x numBlocks) extracted features
            %   audioFile (N x 1) audio file for extracting rhythmic features
            %
            % output:
            %   aggrFeatures: (18 x 1) total features

            % get the size of the feature matrix
            [feats, numBlocks] = size(features);

            % initialize output to zero matrix (account for extra rhythmic features)
            aggrFeatures = zeros(2*feats,1);

            % calculate the mean and variance of each feature
            for i = 1:feats
                tot1 = 0;
                tot2 = 0;
                for j = 1:numBlocks-3
                    tot1 = tot1 + features(i,j);
                end
                mean = tot1/numBlocks;
                for j = 1:numBlocks-3        
                    tot2 = tot2 + (features(i,j) - mean)^2;
                end
                var = tot2/numBlocks;
                aggrFeatures(i,1) = mean;
                aggrFeatures(i+feats,1) = var;
            end
            
            % extract rhythmic features
            [estBPM,  hist, strength] = beatDetect(app, audioFile);
            rhythmFeats = zeros(4,1);
            [rhythmFeats(1,1), rhythmFeats(2,1), rhythmFeats(3,1), rhythmFeats(4,1)] = rhythmFeatures(app, estBPM, hist, strength);
            
            % add rhythm features to feature matrix
            aggrFeatures = [aggrFeatures;rhythmFeats];
            
        end
        
        function [mfccs] = melFreq(app, xb, fftLength, melNum, fs)

            %% Audio Features: Mel Frequency Cepstral Coefficients
            % Extracts the first 3 MFCCs of each block from a blocked audio file
            % and returns a feature vector for each.

            % input:
            %   xb:     (blockSize x numBlocks) blocked audio signal
            %
            % output:
            %   mfccs:  (melNum x numBlocks) feature matrix of each mel cepstral
            %   coefficient for each block

            % get number of blocks and block size of input signal
            [block,numBlocks] = size(xb);

            % get bin size
            K = fftLength/2+1;

            % create window matrix with Hann window
            window = hannWindow(app, block);
            window = window * ones(1,numBlocks);

            % compute magnitude spectrogram 
            X = fft(xb .* window);        % compute fft after windowing
            X = X(1:K, :);                % discard redundant part of the fft
            X = abs(X);                   % take magnitude

            % intialize center frequency matrix
            binFreqs = zeros(K, 1);

            % get center frequency of each bin
            for k = 1:K
                binFreqs(k,1) = (k/fftLength) * fs;
            end

            % initialize mel matrix
            melX = zeros(size(X));

            % compute mel-warped magnitude spectrum
            for n = 1:numBlocks
                for f = 1:K
                    melX(f,n) = 1000*log2(1+X(f,n)/1000);
                end
            end
            melX = abs(melX);

            % initilaize mfcc feature matrix
            mfccs = zeros(melNum,numBlocks);

            % calculate the mel cepstral coefficients
            for j = 1:melNum
                for n = 1:numBlocks
                    tot = 0;
                    for k = 1:K
                        tot = tot + log(melX(k,n))*cos(j*(k-1/2)*(pi/K));
                    end
                    mfccs(j,n) = tot;
                end
            end
            
        end
        
        function [specCentroid] = spectralCentroid(app, xb, fftLength, fs)

            %% Audio Feature: Spectral Centroid 
            % Extracts the spectral centroid of each block of a blocked audio file (xBlock)
            % and returns a feature value matrix

            % input:
            %   xb:             (blockSize x numBlocks) blocked audio file
            %
            % output:
            %   specCentroid:   (1 x numBlocks) feature values per block

            % get number of blocks and block size
            [block,numBlocks] = size(xb);

            % create window matrix with Hann window
            window = hannWindow(app, block);
            window = window * ones(1,numBlocks);

            % apply window to blocked signal
            xHann = xb .* window;

            % compute magnitude spectrogram 
            X = fft(xHann, fftLength);      % compute fft after windowing with input length
            X = X(1:fftLength/2 + 1,:);    % discard redundant part of the fft
            X = abs(X); 

            % calculate spectral centroid of each block
            centroid = zeros(1,numBlocks);      % initialize temporary vector
            specCentroid = zeros(1,numBlocks);  % initialize feature vector
            for n = 1:numBlocks
                ntot = 0;
                dtot = 0;
                for k = 1:block/2-1
                    num = k * abs(X(k,n))^2;
                    denom = abs(X(k,n))^2;
                    ntot = ntot + num;
                    dtot = dtot + denom;
                end
                centroid(1,n) = ntot/dtot;
                specCentroid(1,n) = (centroid(1,n)/block) * fs;
            end
            
        end
        
        function [specFlux] = spectralFlux(app, xb, fftLength)

            %% Audio Feature: Spectral Flux
            % Extracts the spectral flux for each block of a blocked audio signal
            % and returns a feature vector of one feature value per each block.

            % input:
            %   xb:     (blockSize x numBlocks) blocked audio file
            %   fftLength: int, length of fft
            %
            % output:
            %   specFlux:   (1 x numBlocks) feature vector

            % get number of blocks and block size of input signal
            [block,numBlocks] = size(xb);

            % create window matrix with Hann window
            window = hannWindow(app, block);
            window = window * ones(1,numBlocks);

            % apply window to blocked signal
            xHann = xb .* window;

            % compute magnitude spectrogram 
            X = fft(xHann, fftLength);      % compute fft after windowing with input length
            X = X(1:fftLength/2 + 1,:);    % discard redundant part of the fft
            X = abs(X); 

            % initialize feature vector
            specFlux = zeros(1,numBlocks);

            % calculate the spectral flux of each block
            for n = 1:numBlocks
                tot = 0;
                for k = 2:block/2-1
                    tot = tot + power(abs(X(k,n))-abs(X(k-1,n)),2);
                end
                sr = sqrt(tot);
                specFlux(1,n) = sr/(block*.5);
            end

        end
        
        function [specRoll] = spectralRolloff(app, xb, fftLength, kPercent)

            %% Audio Feature: Spectral Rolloff
            % Extracts the spectral rolloff of each block of a blocked audio signal
            % and returns one feature value per block.

            % input:
            %   xb:     (blockSize x numBlocks) blocked audio file
            %   kPercent:   integer, percentage of accumulated magnitudes
            %
            % output:
            %   specRoll:   (1 x numBlocks) feature vector

            % get number of blocks and block size of input signal
            [block,numBlocks] = size(xb);

            % create window matrix with Hann window
            window = hannWindow(app, block);
            window = window * ones(1,numBlocks);

            % apply window to blocked signal
            xHann = xb .* window;

            % compute magnitude spectrogram 
            X = fft(xHann, fftLength);      % compute fft after windowing with input length
            X = X(1:fftLength/2 + 1,:);    % discard redundant part of the fft
            X = abs(X); 

            % calculate accumulated magnitudes for each block
            specTot = zeros(1,numBlocks);
            for n = 1:numBlocks
                stot= 0;
                for k = 1:fftLength/2-1
                    stot = stot + abs(X(k,n));
                end
                specTot(1,n) = stot * kPercent;
            end

            % initialize feature vector
            specRoll = zeros(1,numBlocks);

            % calculate spectral rolloff of each block
            for n = 1:numBlocks
                tot = 0;
                for k = 1:fftLength/2-1
                    tot = tot + abs(X(k,n));
                    if (tot > specTot(1,n))
                        specRoll(1,n) = k;
                        break;
                    end    
                end
            end
    
        end
        
        function [zeroCross] = zeroCrossing(app, xb)

            %% Audio Feature: Zero Crossing Rate
            % Extracts the zero crossing rate of each block in a blocked audio signal
            % and returns one feature value per block.

            % input:
            %   xb:     (blockSize x numBlocks) blocked audio signal
            %
            % output:
            %   zeroCross:      (1 x numBlocks) ZCR feature vector

            % get number of blocks and block size
            [block,numBlocks] = size(xb);

            % intitalize feature vector
            zeroCross = zeros(1,numBlocks);

            % calculate the zero crossing rate of each block (without coefficient)
            for n = 1:numBlocks
                tot = 0;
                for k = 2:block
                    tot = tot + abs(sign(xb(k,n)) - sign(xb(k-1,n)));
                end
                zeroCross(1,n) = tot;
            end

            % implement coefficient
            zeroCross = zeroCross * (1/(2*block));

        end
        
        function [normFeatureMatrix] = normalizeFeatures(app, features)

            %% Z-Score Normalization
            % Uses z-score normalization to normalize the features extracted from a
            % given data set. 
            %
            % input:
            %   features:  (features x numFiles) extracted features from the audio
            %   files
            %
            % Outputs:
            %   normFeatureMatrix:  (features x numFiles) normalized features extracted

            % get size of the feature matrix
            [feats,numFiles] = size(features);

            % intialize matrix to hold the mean and standard deviation of each feature
            meanStd = zeros(feats,2);

            % get the mean and standard deviation for each feature
            for i = 1:feats
                mtot = 0;
                stot = 0;
                for j = 1:numFiles
                    mtot = mtot + features(i,j);
                end
                mean = mtot/numFiles;
                for k = 1:numFiles         
                    stot = stot + (features(i,j) - mean)^2;
                end
                std = sqrt(stot/numFiles);
                meanStd(i,1) = mean;
                meanStd(i,2) = std;
            end

            % initialize normalized feature matrix
            normFeatureMatrix = features;

            % normalize each feature (z-score)
            for i = 1:feats
                for j = 1:numFiles
                    normFeatureMatrix(i,j) = (features(i,j) - meanStd(i,1))/meanStd(i,2);
                end
            end

        end

        function class = kNN(app, featureSpace, audiofile, k)
            
            %% K-Nearest Neighbor Search Algorithm
            % Takes the feature vector for an audio signal and determines the genre by
            % implementing the k-nearest nieghbor method.
            %
            % input:
            %   featureSpace:      (numFeats x numFiles) aggregated features of audio file
            %
            %   audiofile:        (numFeats x 1) features of file to be considered
            %   k:                  int, number of closest neighbors to consider
            %
            % output:
            %   class:            int, 0 for like, 1 for dislike
            
            % get size of feature space
            [feats,files] = size(featureSpace);
            
            % add slot for tag on audiofile
            audiofile = [0;audiofile];
            
            % calculate euclidean distance of each file in the feature space
            dist = zeros(files, 1);
            for i = 1:files
                tot = 0;
                for n = 2:feats
                    tot = tot + (audiofile(n,1) - featureSpace(n,i))^2;
                end
                dist(i,1) = sqrt(tot);
            end
            
            % sort distances (ascending) and find k minimums
            sDist = sort(dist);
            kMins = sDist(1:k,1);
            
            % find k files which are closest
            neighbors = zeros(k,1);
            for i = 1:k
                for n = 1:files
                    if (kMins(i,1) == dist(n,1))
                        neighbors(i,1) = featureSpace(1,n);
                    end
                end
            end
            
            % count number of like and dislike tags in k neighbors
            likeCount = 0;
            disCount = 0;
            for i = 1:k
                if (neighbors(i,1) == 0)
                    likeCount = likeCount + 1;
                else
                    disCount = disCount + 1;
                end
            end
            
            % if there is a tie, play the song anyway (label as like)
            if (likeCount >= disCount)
                class = true;
            elseif (likeCount < disCount)
                class = false;
            end
            
        end
        
        function [est_bpm, B, strength] = beatDetect(app, s)
            
            %% Audio Feature: Beat Detection and Beat Historam Extraction
            % Extracts the tempo and other rhythmic features from the audio signal
            %
            % input:
            %   s:     (N x 1) audio file
            %
            % output:
            %   est_bpm:    float, the estimated tempo of the song
            %   B:          x axis of beat histogram
            %   strength:   y axis of beat histogram
            
            fs = app.sampleFreq;
            
            levels = 4; 

            l = length(s);
            k = mod(l,2);
            s = s(1:l-k);
            s1 = buffer(s,4*fs,2*fs); % framing the signal in 4 sec windows with 50% of overlap
            [~,s_2] = size(s1);

            % initializing the input for the first level of decomposition
            lp(1).data = s1;

            for k = 1:s_2
               for j = 2:levels+1 
                   [lp(j).data(:,k),hp(j).data(:,k)] = dwt(lp(j-1).data(:,k),'db4'); %DWT
                   lp(j).data(:,k) = filter(0.01,0.99,hp(j).data(:,k)); %fitlering the bands
                   final(j).data(:,k) = (abs(hp(j).data(1:2^(5-j):end,k))-mean(abs(hp(j).data(1:2^(5-j):end,k)))); %full wave rectification, downsample and mean removal
               end
            end

            for i = 2:5
                S(i-1,1:2) = size(final(i).data);
            end
            sum = zeros(min(S(:,1)),min(S(:,2)));

            for i = 2:5
                sum = sum + final(i).data(1:min(S(:,1)),1:min(S(:,2)));
                g = final(i).data(1:min(S(:,1)),1:min(S(:,2)));
            end

            K = length(sum);
            %  
            for i = 1:s_2
                lp(5).data(:,i) = filter(0.01,[1 -0.99],lp(5).data(:,i));
                temp(:,i) = abs(lp(5).data(:,i))-mean(abs(lp(5).data(:,i)));
            end

            temp = temp(1:K,:);
            sum = sum + temp;

            % ACF 
            for i = 1:s_2
               correl_temp = xcorr(sum(:,i)); 
               correl(:,i) = correl_temp(length(correl_temp)/2 + 1 : end);
            end

            %Time Axis
            time = ((1:length(s))./ (fs/16)); 
            time = time(1:length(time)-1);
 
            bpm = 60 ./ time;
            o = find(bpm <= 220 & bpm >= 40);
   
            bpm2 = bpm(o);

            for i=1:s_2
               cM2(:,i) = correl(o(:),i);
               [v(i),p(i)] = max(cM2(:,i));
               est_bpm(i) = bpm2(p(i));
            end

            [B,Q] = sort(est_bpm);
            [~,e] = size(Q);
            for i = 1:e
                strength(i) = v(Q(i));
            end

            %plot(B,strength);
            % find the median bpm value of all windows
            est_bpm = median(est_bpm); 
        end

        function [tempo, peakRatio, relAmp, sumAmp] = rhythmFeatures(app, estBPM, hist, strength)
            
            %% Audio Features: Rhythmic Features
            % Takes the estimated tempo and beat histogram from the histogram function
            % and outputs the four beat features to be used.
            %
            % input:
            %   estBPM:    float, estimated bpm of the song
            %   histogram: x axis of the beat histogram
            %   strength:  y axis of the beat histogram
            %
            % output:
            %   tempo:     float, detected tempo of the song
            %   peakRatio: float, ratio of the two highest peaks in the beat histogram
            %   relAmp:    float, relative amplitude of the highest peak of the 
            %   sumAmp:    float, total sum of the beat histogram (beat strength)
            
            % set the estimated bpm as the tempo of the song
            tempo = estBPM;
            
            % get the peaks of the histogram
            [peaks, ~] = findpeaks(strength);
            sPeaks = sort(peaks);
            
            % calculate ratio between highest and second highest peak
            % (if there were 2 detected, else set ratio to 0)
            if (length(sPeaks) >= 2)
                peakRatio = sPeaks(1)/sPeaks(2);
            else
                peakRatio = 0;
            end
            
            % find amplitude of highest peak
            [~,highIndex] = max(strength);
            highAmp = hist(highIndex);
            
            % calculate total amplitude sum of the beat histogram
            sumAmp = 0;
            for i = 1:length(strength)
                sumAmp = sumAmp + strength(i);
            end
            
            % calculate relative amplitude of highest peak to total sum
            relAmp = highAmp/sumAmp;
            
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize current song to the first one
            app.currentSong = 1;
            
            % Initialize playlist start to false
            app.playStart = false;
            
            % Set number of features
            app.numFeats = 18;
            
            % Initialize block size and hop size
            app.blockSize = 1024;
            app.hopSize = 512;
            
            % Initialize empty feature spaces for liked/disliked songs
            app.likeSpace = [];
            app.disSpace = [];
            
            % Initialize k value to 1
            app.kValue = 1;
            
            % Initiliaze k changer to 0
            app.changeK = 0;
            
            % Initialize overflow playlist
            %app.overPlaylist = {};
            app.overFeatures = [];
            app.overSize = 0;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % Initialize both buttons to false
            app.likeSong = false;
            app.disSong = false;
            
            % Determine if the song was liked or disliked
            if (selectedButton == app.LikeButton)
                app.likeSong = true;
            else
                app.disSong = true;
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Stop current song
            oldPlayer = app.player;
            stop(oldPlayer);
            
            % Add and label song to Like or Dislike space based on user input
            song = app.currentSong;
            likeFeatures = app.likeSpace;
            disFeatures = app.disSpace;
            features = app.featureMatrix;
            if (app.likeSong)
                % 0 = like
                tagFeature = [0; features(:,song)];
                likeFeatures = [likeFeatures tagFeature];
            elseif (app.disSong)
                % 1 = dislike
                tagFeature = [1; features(:,song)];
                disFeatures = [disFeatures tagFeature];
            end
            
            % Update like and dislike feature spaces
            app.likeSpace = likeFeatures;
            app.disSpace = disFeatures;
            
            % Get number of liked and disliked songs
            [~, numLike] = size(likeFeatures);
            [~, numDis] = size(disFeatures);
            
            % Get current k value for app
            k = app.kValue;
            
            % Start kNN algorithm after at least 3 songs have been added to both Like and Dislike feature space
            if (numLike >= 3 && numDis >= 3)          
                % Run kNN on next songs
                likeFound = false;
                featureSpace = [likeFeatures disFeatures];
                % Advance to next song
                song = song + 1;
                count = 0;
                while (~likeFound)
                    % Keep track of iteration number
                    count = count + 1;
                    audiofile = features(:,song);
                    likeFound = kNN(app, featureSpace, audiofile, k);
                    % Determine if the song was more similar to like or dislike space
                    if (likeFound)
                        app.currentSong = song;
                        % Increase k value
                        if (k < 9 && app.changeK == 1)
                            app.kValue = k + 1;
                            app.changeK = 0;
                        elseif (app.changeK == 0)
                            app.changeK = 1;
                        end
                        % If end of playlist occurs, start overflow playlist
                        if (song >= app.numAudio)
                            app.playlist = app.overPlaylist;
                            app.featureMatrix = app.overFeatures;
                            app.numAudio = app.overSize;
                            % Reset parameters
                            app.currentSong = 1;
                            app.overPlaylist = {};
                            app.overFeatures = [];
                            app.overSize = 0;
                        end
                    else
                        % Break if 20 iterations found no like song
                        if (count > 20)
                            app.currentSong = song;
                            break;
                        else
                            % Add skipped song's features and audio to overflow playlist
                            music = app.playlist;
                            app.overSize = app.overSize + 1;
                            app.overPlaylist{app.overSize} = music{song};
                            songFeatures = features(:,song);
                            app.overFeatures = [app.overFeatures songFeatures];
                            app.playlist = music;
                            song = song + 1;
                            % If end of playlist occurs, start overflow playlist
                            if (song >= app.numAudio)
                                app.playlist = app.overPlaylist;
                                app.featureMatrix = app.overFeatures;
                                app.numAudio = app.overSize;
                                % Reset parameters
                                app.currentSong = 1;
                                app.overPlaylist = {};
                                app.overFeatures = [];
                                app.overSize = 0;
                                break;
                            end
                        end
                    end
                end
            else
                % If before 3 songs liked and disliked, play the next song
                app.currentSong = song + 1;
            end
            
            % Advance to next song
            songIndex = app.currentSong;
            
            % Switch to next song
            fs = app.sampleFreq;
            music = app.playlist;
            app.player = audioplayer(music{songIndex},fs);
            newPlaylist = app.player;
            play(newPlaylist);
            app.Button.Text = checkGenre(app);
            
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            % Assign current song to audio player
            music = app.player;
                        
            % Check if the button is pressed
            value = app.PlayPauseButton.Value;
            if (value)
                % Change text on button
                app.PlayPauseButton.Text = 'Pause';
                % Special case - if first song
                start = app.playStart;
                if (~start)
                    if (app.currentSong == 1)
                        play(music);
                        app.Button.Text = checkGenre(app);
                        app.playStart = true;
                    end
                else
                    resume(music);
                end
            else
                app.PlayPauseButton.Text = 'Play';
                % Pause the current song
                pause(music);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Save current directory
            codeFolder = pwd;
            
            % Open box for user to select their music folder
            musicPath = uigetdir;
            cd(musicPath);
            
            % Get the audio files from the selected folder
            musicFolder = dir('*.au*');
            
            % Get the number of audio files in the folder
            numFiles = length(musicFolder);
            app.numAudio = numFiles;
            
            % Read in the audio files (combine right/left to get mono signal)
            for i = 1:numFiles
                [file, fs] = audioread(musicFolder(i).name);
                %monoFile = zeros(length(file),1);
                %for j = 1:length(file)
                    %monoFile(j,1) = (file(j,1) + file(j,2))/2;
                %end
                musicFiles{i} = file;
            end
            
            % Set sampling frequency for app
            app.sampleFreq = fs;            
            
            % Add tags to each file for evaluation purposes (target or not target genre)
            targetFiles = numFiles/2;
            index = 1;
            for i = 1:2
                for j = 1:targetFiles
                    genreTag = i*.01;
                    musicFiles{index} = [musicFiles{index};genreTag];
                    index = index+1;
                end
            end
            
            % Shuffle files into a random order
            app.playlist = musicFiles(randperm(numFiles));
            music = app.playlist;
            songIndex = app.currentSong;
                        
            app.player = audioplayer(music{songIndex},fs);
            
            % Return to code folder
            cd(codeFolder);
            
            % Set block size and hop size
            block = app.blockSize;
            hop = app.hopSize;
            
            % Extract features from all audio files
            features = zeros(app.numFeats, numFiles);
            load = waitbar(0, 'Loading playlist...');
            for i = 1:numFiles
                audioFile = music{i};
                tempFeatures = extractFeatures(app, audioFile, block, hop, fs);
                features(:,i) = aggregateFeatures(app, tempFeatures, audioFile);
                % Show loading bar
                waitbar(i/numFiles);
            end
            close(load);
            
            % Normalize the features
            app.featureMatrix = normalizeFeatures(app, features);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.PlayPauseButton = uibutton(app.UIFigure, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Text = 'Play';
            app.AdaptiveShuffleLabel = uilabel(app.UIFigure);
            app.AdaptiveShuffleLabel.Text = 'Adaptive Shuffle';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.LikeButton = uitogglebutton(app.ButtonGroup);
            app.LikeButton.Text = 'Like';
            app.LikeButton.Value = true;
            app.DislikeButton = uitogglebutton(app.ButtonGroup);
            app.DislikeButton.Text = 'Dislike';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.GenreLabel = uilabel(app.UIFigure);
            app.GenreLabel.Text = 'Genre';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AdaptiveShuffle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end